classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Value1EditFieldLabel  matlab.ui.control.Label
        Value1EditField       matlab.ui.control.NumericEditField
        Value2EditFieldLabel  matlab.ui.control.Label
        Value2EditField       matlab.ui.control.NumericEditField
        AnswerEditFieldLabel  matlab.ui.control.Label
        AnswerEditField       matlab.ui.control.NumericEditField
        AddButton             matlab.ui.control.Button
        MultiplyButton        matlab.ui.control.Button
        SquareButton          matlab.ui.control.Button
        SubtractButton        matlab.ui.control.Button
        DivideButton          matlab.ui.control.Button
        SquareRootButton      matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
           % Getting value from field 1
          a = app.Value1EditField.Value;
          % Getting value from field 2
          b = app.Value2EditField.Value;
          c = a + b;
          % Displaying answer
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
          a = app.Value1EditField.Value;
          b = app.Value2EditField.Value;
          c = a * b;
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
          a = app.Value1EditField.Value;
          app.Value2EditField.Value = 0;
          c = a.^2;
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
          a = app.Value1EditField.Value;
          b = app.Value2EditField.Value;
          c = a - b;
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: DivideButton
        function DivideButtonPushed(app, event)
          a = app.Value1EditField.Value;
          b = app.Value2EditField.Value;
          c = a / b;
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: SquareRootButton
        function SquareRootButtonPushed(app, event)
          a = app.Value1EditField.Value;
          app.Value2EditField.Value = 0;
          c = sqrt(a);
          app.AnswerEditField.Value = c;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
          % Clearing the previous value to 0
          app.Value1EditField.Value = 0;
          % Clearing the previous value to 0
          app.Value2EditField.Value = 0;
          % Clearing the previous value to 0
          app.AnswerEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Value1EditFieldLabel = uilabel(app.UIFigure);
            app.Value1EditFieldLabel.Text = 'Value 1';
            app.Value1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Value2EditFieldLabel = uilabel(app.UIFigure);
            app.Value2EditFieldLabel.Text = 'Value 2';
            app.Value2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AnswerEditFieldLabel = uilabel(app.UIFigure);
            app.AnswerEditFieldLabel.Text = 'Answer';
            app.AnswerEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnswerEditField.Editable = 'off';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MultiplyButton = uibutton(app.UIFigure, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.DivideButton = uibutton(app.UIFigure, 'push');
            app.DivideButton.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.DivideButton.Text = 'Divide';
            app.SquareRootButton = uibutton(app.UIFigure, 'push');
            app.SquareRootButton.ButtonPushedFcn = createCallbackFcn(app, @SquareRootButtonPushed, true);
            app.SquareRootButton.Text = 'Square Root';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end